classdef Clicky_GUI_For_Chrissi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SinFitPlot                     matlab.ui.control.UIAxes
        EquiphotonFluxPlot             matlab.ui.control.UIAxes
        PowerAtEquiphotonFlux          matlab.ui.control.UIAxes
        DataTable                      matlab.ui.control.Table
        CommandValueVSliderLabel       matlab.ui.control.Label
        CommandValueVSlider            matlab.ui.control.Slider
        WavelengthnmSliderLabel        matlab.ui.control.Label
        WavelengthnmSlider             matlab.ui.control.Slider
        PhotonsPerPulseEditFieldLabel  matlab.ui.control.Label
        PhotonsPerPulseEditField       matlab.ui.control.NumericEditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        FilenameEditField              matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        function results = updatePlots(app)
            wavelength = app.WavelengthnmSlider.Value;
            CMD = app.CommandValueVSlider.Value;
            
            [photon_flux_at_wavelength, equi_photon_cmd_value, unique_wavelengths, milliwatts_at_command_at_wavelength, filename] = Compute_CMD_Values_Func(wavelength, CMD, app.SinFitPlot, app.EquiphotonFluxPlot, app.PowerAtEquiphotonFlux);
            
            app.DataTable.Data = [unique_wavelengths, equi_photon_cmd_value, milliwatts_at_command_at_wavelength];
            app.PhotonsPerPulseEditField.Value = photon_flux_at_wavelength;
            
            app.FilenameEditField.Value = filename;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CommandValueVSlider
        function CommandValueVSliderValueChanged(app, event)
            app.updatePlots();
        end

        % Value changed function: WavelengthnmSlider
        function WavelengthnmSliderValueChanged(app, event)
            app.WavelengthnmSlider.Value = round(app.WavelengthnmSlider.Value/10)*10;
            app.updatePlots();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SinFitPlot = uiaxes(app.UIFigure);
            title(app.SinFitPlot, 'Sine Fit Plot')
            xlabel(app.SinFitPlot, '')
            ylabel(app.SinFitPlot, 'Photon Count')
            app.EquiphotonFluxPlot = uiaxes(app.UIFigure);
            title(app.EquiphotonFluxPlot, 'Equiphoton Flux')
            xlabel(app.EquiphotonFluxPlot, '')
            ylabel(app.EquiphotonFluxPlot, 'Command Value')
            app.PowerAtEquiphotonFlux = uiaxes(app.UIFigure);
            title(app.PowerAtEquiphotonFlux, 'Power at Equiphoton Flux')
            xlabel(app.PowerAtEquiphotonFlux, '')
            ylabel(app.PowerAtEquiphotonFlux, 'Power (mW)')
            app.DataTable = uitable(app.UIFigure);
            app.DataTable.ColumnName = {'Wavelength'; 'CMD Value'; 'Power'};
            app.DataTable.RowName = {};
            app.CommandValueVSliderLabel = uilabel(app.UIFigure);
            app.CommandValueVSliderLabel.Text = 'Command Value (V)';
            app.CommandValueVSlider = uislider(app.UIFigure);
            app.CommandValueVSlider.Limits = [0 1];
            app.CommandValueVSlider.ValueChangedFcn = createCallbackFcn(app, @CommandValueVSliderValueChanged, true);
            app.WavelengthnmSliderLabel = uilabel(app.UIFigure);
            app.WavelengthnmSliderLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmSlider = uislider(app.UIFigure);
            app.WavelengthnmSlider.Limits = [750 1080];
            app.WavelengthnmSlider.ValueChangedFcn = createCallbackFcn(app, @WavelengthnmSliderValueChanged, true);
            app.WavelengthnmSlider.Value = 800;
            app.PhotonsPerPulseEditFieldLabel = uilabel(app.UIFigure);
            app.PhotonsPerPulseEditFieldLabel.Text = 'Photons Per Pulse';
            app.PhotonsPerPulseEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Clicky_GUI_For_Chrissi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end